classdef auxDialogBox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LabelnameEditField       matlab.ui.control.EditField
        LabelnameEditFieldLabel  matlab.ui.control.Label
        CancelButton             matlab.ui.control.Button
        OkButton                 matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parentApp % store the label here
        rgb % store output from color picker
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;

            axtoolbar(app.UIAxes,{}); % disable toolbar

            app.rgb = [1 0 0];
            app.UIAxes.Color = app.rgb;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            rgb = uisetcolor

            if any(rgb ~= [1,1,1])
                app.UIAxes.Color = rgb;
                app.rgb = rgb;
            end
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % check that the textbox is not empty
            if app.LabelnameEditField.Value ~= "" 
                app.parentApp.newLabel.('Name') = app.LabelnameEditField.Value; 
                app.parentApp.newLabel.('Color') = app.rgb;
            end

            app.delete()
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % do not add a new label
            app.delete()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.OkButton = uibutton(app.GridLayout, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.GridLayout, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.LabelnameEditFieldLabel = uilabel(app.GridLayout);
            app.LabelnameEditFieldLabel.Text = 'Label name: ';
            app.LabelnameEditField = uieditfield(app.GridLayout, 'text');
            app.LabelnameEditField.CharacterLimits = [0 40];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = auxDialogBox(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end