classdef LabelContainer < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        Box          matlab.ui.container.GridLayout
        LabelsPanel  matlab.ui.container.Panel
        LabelLayout  matlab.ui.container.GridLayout
        addLabelBtn  matlab.ui.control.Button
    end

    properties (Access = public)
        currentlySelected = -1; % the index of the currently selected label
        labels = {};
        newLabel
    end
    
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: addLabelBtn
        function addLabelBtnButtonPushed(comp, event)
            % Disable the main app window
            comp.addLabelBtn.Enable = 'off';

            % Create a new modal dialog
            comp.newLabel = struct(); % initialize a new candidate label
            
            dialogBox = auxDialogBox(comp);
            % wait until label setup complete
            waitfor(dialogBox);
            comp.addLabelBtn.Enable = 'on';
            comp.newLabel
            if ~isequal(comp.newLabel,struct())
                % request demente for a new label
                % (duplicate names ...)
                % if demente says "ok"
                addNewLabel(comp, comp.newLabel.Name);
    
                % add a new row to the gridLayout
                comp.LabelLayout.RowHeight(end)   = {30};
                comp.LabelLayout.RowHeight(end+1) = {25};
    
                % move the button down
                comp.addLabelBtn.Layout.Row = comp.addLabelBtn.Layout.Row + 1;
    
                lentry = LabelEntry(comp.LabelLayout);
                lentry.label = comp.newLabel;
                lentry.Layout.Row = comp.addLabelBtn.Layout.Row - 1; % place the label in the row above the moved button
                lentry.Layout.Column = comp.addLabelBtn.Layout.Column; % there is only one column
            else
                disp("Empty label")
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 355 560];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Box = uigridlayout(comp);
            comp.LabelsPanel = uipanel(comp.Box);
            comp.LabelsPanel.Title = 'Labels';
            comp.LabelLayout = uigridlayout(comp.LabelsPanel);
            comp.addLabelBtn = uibutton(comp.LabelLayout, 'push');
            comp.addLabelBtn.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @addLabelBtnButtonPushed, true);
            comp.addLabelBtn.Text = '+';
        end
    end
end