classdef LabelEntry < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        removeBtn   matlab.ui.control.Button
        CheckBox    matlab.ui.control.CheckBox
        Label       matlab.ui.control.Label
        UIAxes      matlab.ui.control.UIAxes
    end

    properties (Access = public)
        idx = -1;
        label
        labelContainer = {};
    end
    
    
    methods (Access = private)

        function removeLabel(comp, src, event)
            comp.labelContainer.removeLabel(comp.idx)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            axtoolbar(comp.UIAxes,{});

        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            if ~isempty(comp.labelContainer) && ~isempty(comp.idx) 
                % We don't use matlab.apps.createCallbackFcnù
                % https://www.mathworks.com/matlabcentral/answers/1899310-custom-control-deleation-from-inside-another-custom-control
                comp.removeBtn.ButtonPushedFcn=@comp.removeLabel;
            end
            if ~isempty(comp.label)
                comp.Label.Text = comp.label.Name;
                comp.UIAxes.Color = comp.label.Color;
            end

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 304 55];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.UIAxes = uiaxes(comp.GridLayout);
            zlabel(comp.UIAxes, 'Z')
            comp.UIAxes.XTick = [];
            comp.UIAxes.YTick = [];
            comp.UIAxes.ZTick = [];
            comp.Label = uilabel(comp.GridLayout);
            comp.CheckBox = uicheckbox(comp.GridLayout);
            comp.CheckBox.Text = '';
            comp.removeBtn = uibutton(comp.GridLayout, 'push');
            comp.removeBtn.Text = '-';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end